classdef CellDensityApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AxisSelectionsPanel          matlab.ui.container.Panel
        XAxisDropDownLabel           matlab.ui.control.Label
        XAxisDropDown                matlab.ui.control.DropDown
        YAxisDropDownLabel           matlab.ui.control.Label
        YAxisDropDown                matlab.ui.control.DropDown
        TabGroup                     matlab.ui.container.TabGroup
        PlotTab                      matlab.ui.container.Tab
        UIAxes                       matlab.ui.control.UIAxes
        LoglogPlotTab                matlab.ui.container.Tab
        UIAxes2                      matlab.ui.control.UIAxes
        CurrentGatingsTab            matlab.ui.container.Tab
        TextAreaLabel                matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        LoadDataButton               matlab.ui.control.Button
        GatingSelectionsPanel        matlab.ui.container.Panel
        VariableDropDownLabel        matlab.ui.control.Label
        VariableDropDown             matlab.ui.control.DropDown
        OptionsDropDownLabel         matlab.ui.control.Label
        OptionsDropDown              matlab.ui.control.DropDown
        SubmitGatingButton           matlab.ui.control.Button
        ClearGatingsButton           matlab.ui.control.Button
        CurrentGatingsTextAreaLabel  matlab.ui.control.Label
        CurrentGatingsTextArea       matlab.ui.control.TextArea
        HoldButton                   matlab.ui.control.Button
        ClearButton                  matlab.ui.control.Button
        STATESWITCHButton            matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        Data % table that holds all quantitative data
        metaData % table that holds all non-numerical data
        x_data % contains the column selected for x-axis
        y_data % contains the column selected for y-axis
        row_index % property that keeps track of which row index will be plotted
        metaCols % column names of the metadata
        
        
    end
    
    methods (Access = private)
        
        % plots data based on most recent dropdown values
        function refreshPlot(app)
            
            % ensures columns to be plotted are up-to-date with dropdown selections 
            app.x_data = app.Data.(app.XAxisDropDown.Value);
            app.y_data = app.Data.(app.YAxisDropDown.Value);
            
            
            % linear scale
%             app.UIAxes.Title.String = app.XAxisDropDown.Value + " vs. " + app.YAxisDropDown.Value;
%             app.UIAxes.XLabel.String = app.XAxisDropDown.Value;
%             app.UIAxes.YLabel.String = app.YAxisDropDown.Value;
            
            scatter(app.UIAxes,app.x_data(app.row_index),app.y_data(app.row_index))
            
            % loglog scale
%             app.UIAxes2.Title.String = app.XAxisDropDown.Value + " vs. " + app.YAxisDropDown.Value;
%             app.UIAxes2.XLabel.String = app.XAxisDropDown.Value;
%             app.UIAxes2.YLabel.String = app.YAxisDropDown.Value;
            
            scatter(app.UIAxes2,app.x_data(app.row_index),app.y_data(app.row_index))
            set(app.UIAxes2,'xscale','log')
            set(app.UIAxes2,'yscale','log')
            
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, test)


        end

        % Value changed function: XAxisDropDown
        function XAxisDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Value changed function: YAxisDropDown
        function YAxisDropDownValueChanged(app, event)
            
            refreshPlot(app)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            
            % Loads in Data
            [Density.file_fbm,Density.path_fbm] = uigetfile({'*.*','Select the fbm data file.'});
            DensityRead = readtable(strcat(Density.path_fbm,Density.file_fbm));
            colNames = DensityRead.Properties.VariableNames;
                    
            app.Data = DensityRead;            
            
            % loads axis dropdown options based on column names from DensityRead
            [nr,nc] = size(app.Data);
            for i = 2:nc
                app.XAxisDropDown.Items(i-1) = colNames(i); 
                app.YAxisDropDown.Items(i-1) = colNames(i); 
            end            


            % Loads in MetaData
            [Density.file_metadata,Density.path_metadata] = uigetfile({'*.*','Select the metadata file.'});
            DensityRead = readtable(strcat(Density.path_metadata,Density.file_metadata));
            colNames = DensityRead.Properties.VariableNames;
            
            app.metaData = DensityRead;
            app.metaCols = colNames;

            % loads metadata dropdown options based on DensityRead
            [nr,nc] = size(app.metaData);
            app.VariableDropDown.Items(1) = {'None'}; % initialize metadata options
            app.OptionsDropDown.Items(:,:) = [];
            app.OptionsDropDown.Items(1) = {'None'};
            for i = 2:nc
                app.VariableDropDown.Items(i) = app.metaCols(i); 
            end
                                    
            % initializes index property
            app.row_index = true([height(app.Data),1]);
           
            
            refreshPlot(app)
        end

        % Value changed function: VariableDropDown
        function VariableDropDownValueChanged(app, event)
            value = app.VariableDropDown.Value;
            
            % clears out the list
            app.OptionsDropDown.Items(:,:) = [];
            
            % loads in all unique options for gating
            [a,b] = unique(app.metaData(:,value),'stable');
            for i = 1:height(a)
                app.OptionsDropDown.Items(i) = a{i,1};
            end
            
        end

        % Button pushed function: SubmitGatingButton
        function SubmitGatingButtonPushed(app, event)
            
            % turns gating selecting into string
            gating = convertCharsToStrings(app.OptionsDropDown.Value);
            % col is the data column chosen for gating
            col = app.VariableDropDown.Value;
                       
            % finds intersection between existing index and new index. this is set as the new row-index
            temp_index = app.metaData{:,col} == gating;
            app.row_index = temp_index & app.row_index;
             
            % display current gatings
            temp = convertCharsToStrings(app.TextArea.Value);
            if temp == ""
                app.TextArea.Value = gating;
            else
                app.TextArea.Value = temp + ", " + gating;
            end
            
            temp = convertCharsToStrings(app.CurrentGatingsTextArea.Value);
            if temp == ""
                app.CurrentGatingsTextArea.Value = gating;
            else
                app.CurrentGatingsTextArea.Value = temp + ", " + gating;
            end
            
            refreshPlot(app)
            
        end

        % Button pushed function: ClearGatingsButton
        function ClearGatingsButtonPushed(app, event)

            app.row_index = true([height(app.Data),1]);
            
            app.TextArea.Value = "";
            app.CurrentGatingsTextArea.Value = "";
            
            refreshPlot(app)
        end

        % Button pushed function: HoldButton
        function HoldButtonPushed(app, event)
            
            if ishold
                hold(app.UIAxes,'off');
                hold(app.UIAxes2,'off');
            else 
                hold(app.UIAxes,'on');
                hold(app.UIAxes2,'on');
            end
                        
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AxisSelectionsPanel = uipanel(app.UIFigure);
            app.AxisSelectionsPanel.Title = 'Axis Selections';
            app.XAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.XAxisDropDownLabel.Text = 'X-Axis';
            app.XAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.XAxisDropDown.Items = {'item1', 'item2', 'item3'};
            app.XAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XAxisDropDownValueChanged, true);
            app.XAxisDropDown.Value = 'item1';
            app.YAxisDropDownLabel = uilabel(app.AxisSelectionsPanel);
            app.YAxisDropDownLabel.Text = 'Y-Axis';
            app.YAxisDropDown = uidropdown(app.AxisSelectionsPanel);
            app.YAxisDropDown.Items = {'item1', 'item2', 'item3'};
            app.YAxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YAxisDropDownValueChanged, true);
            app.YAxisDropDown.Value = 'item1';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.PlotTab = uitab(app.TabGroup);
            app.PlotTab.Title = 'Plot';
            app.UIAxes = uiaxes(app.PlotTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LoglogPlotTab = uitab(app.TabGroup);
            app.LoglogPlotTab.Title = 'Loglog Plot';
            app.UIAxes2 = uiaxes(app.LoglogPlotTab);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CurrentGatingsTab = uitab(app.TabGroup);
            app.CurrentGatingsTab.Title = 'Current Gatings';
            app.TextAreaLabel = uilabel(app.CurrentGatingsTab);
            app.TextAreaLabel.Text = 'Text Area';
            app.TextArea = uitextarea(app.CurrentGatingsTab);
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.GatingSelectionsPanel = uipanel(app.UIFigure);
            app.GatingSelectionsPanel.Title = 'Gating Selections';
            app.VariableDropDownLabel = uilabel(app.GatingSelectionsPanel);
            app.VariableDropDownLabel.Text = 'Variable';
            app.VariableDropDown = uidropdown(app.GatingSelectionsPanel);
            app.VariableDropDown.Items = {'item1'};
            app.VariableDropDown.ValueChangedFcn = createCallbackFcn(app, @VariableDropDownValueChanged, true);
            app.VariableDropDown.Value = 'item1';
            app.OptionsDropDownLabel = uilabel(app.GatingSelectionsPanel);
            app.OptionsDropDownLabel.Text = 'Options';
            app.OptionsDropDown = uidropdown(app.GatingSelectionsPanel);
            app.OptionsDropDown.Items = {'item1'};
            app.OptionsDropDown.Value = 'item1';
            app.SubmitGatingButton = uibutton(app.GatingSelectionsPanel, 'push');
            app.SubmitGatingButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitGatingButtonPushed, true);
            app.SubmitGatingButton.Text = 'Submit Gating';
            app.ClearGatingsButton = uibutton(app.GatingSelectionsPanel, 'push');
            app.ClearGatingsButton.ButtonPushedFcn = createCallbackFcn(app, @ClearGatingsButtonPushed, true);
            app.ClearGatingsButton.Text = 'Clear Gatings';
            app.CurrentGatingsTextAreaLabel = uilabel(app.GatingSelectionsPanel);
            app.CurrentGatingsTextAreaLabel.Text = 'Current Gatings';
            app.CurrentGatingsTextArea = uitextarea(app.GatingSelectionsPanel);
            app.HoldButton = uibutton(app.UIFigure, 'push');
            app.HoldButton.ButtonPushedFcn = createCallbackFcn(app, @HoldButtonPushed, true);
            app.HoldButton.Text = 'Hold';
            app.ClearButton = uibutton(app.UIFigure, 'push');
            app.ClearButton.Text = 'Clear';
            app.STATESWITCHButton = uibutton(app.UIFigure, 'state');
            app.STATESWITCHButton.Text = 'STATE SWITCH';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CellDensityApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end